classdef AxonSkewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectgButton                  matlab.ui.control.Button
        TotalorthogonalEditField       matlab.ui.control.NumericEditField
        TotalorthogonalEditFieldLabel  matlab.ui.control.Label
        StopselectingButton            matlab.ui.control.Button
        OutputstatusTextArea           matlab.ui.control.TextArea
        OutputstatusTextAreaLabel      matlab.ui.control.Label
        ConfirmoutputButton            matlab.ui.control.Button
        DisplayonskeletonButton        matlab.ui.control.Button
        SaveoutlineButton              matlab.ui.control.Button
        DrawoutlineButton              matlab.ui.control.Button
        DeleteoutlineButton            matlab.ui.control.Button
        ImagepathTextArea              matlab.ui.control.TextArea
        ImagepathTextAreaLabel         matlab.ui.control.Label
        LoadskeletonButton             matlab.ui.control.Button
        LoadrawButton                  matlab.ui.control.Button
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        clickedPts  %points clicked when drawing brain border in image 1
        drawnLineHandles %graphic handles for the drawing, lines
        markerHandles %graphic handles for the drawing, markers
        orthoLineHandles %graphic handles for the orthogonal lines in image 2
        markerHandles2
        drawnLineHandles2
        totalOrthL %number of orthogonal lines to draw
        orthoAll %cerry array that stores the slope and intercepts and starting points of the orthogonal lines
        img1Path %file path of the image 1
        isDrawing = false %state, whether the app is drawing 
        isSelecting = false %state, whether the app is taking key press g as orthogonal line selection
        selectedIdx
        selectedOrtho = [] %final result
    end
    
    methods (Access = private)
        
        function onMouseClick(app, src, evt) %function dealing with drawing SC border
            if ~app.isDrawing
                return
            end

            clickedObj = src.CurrentObject;   % could be the axes, an image primitive, a button, etc.
            ax = [];
            if ~isempty(clickedObj) && isgraphics(clickedObj)
                ax = ancestor(clickedObj, 'matlab.graphics.axis.Axes');
            end

            % If the click was not inside our UIAxes1, ignore it
            if isempty(ax) || ax ~= app.UIAxes1
                return;
            end

            cp =ax.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);

            app.clickedPts(end+1,:) = [x y];
            g = plot(app.UIAxes1, x, y, 'ro', 'MarkerSize',6);
            app.markerHandles(end+1) = g;
            if size(app.clickedPts,1) > 1
                p1 = app.clickedPts(end-1,:);
                p2 = app.clickedPts(end,:);

                h = line(app.UIAxes1, ...
                    [p1(1) p2(1)], [p1(2) p2(2)], ...
                    'Color','r','LineWidth',2);
                app.drawnLineHandles(end+1) = h;
            end
        end
        
        function [tl, segl] = border_seg_length(app)
            [ptn, ~] = size(app.clickedPts);
            %ptn = numel(app.clickedPts)/2;
            tl = 0;
            segl = zeros([ptn-1, 1]);
            for i =2:ptn
                v = app.clickedPts(i, :)- app.clickedPts(i-1, :);
                %l = l + norm(v);
                segl(i-1) = norm(v);
                tl = tl+segl(i-1);
            end
            fprintf('Total segement length: %d\n', tl);
        end

        function calculate_orthognals(app)
            app.orthoAll = [];
            [total_len, seglen] = border_seg_length(app);
            %tidx = 0;
            for i = 1:numel(seglen)
                p1 = app.clickedPts(i, :);
                p2 = app.clickedPts(i+1, :);
                d = p2-p1;
                if (seglen(i) == 0)
                    warning('Segment %d: p1 and p2 are identical, skipping.', i);
                    %app.orthoAll{i} = [];
                    continue;
                end

                n_orth = ceil(seglen(i)/total_len * app.totalOrthL);
                if (n_orth==0)
                    n_orth = 1;
                end
                
                t = d/seglen(i);
                normal = [-t(2), t(1)];

                s = (1:n_orth)/(n_orth+1);
                % Points along the segment
                pts = p1 + s(:) * d;  % n-by-2
                % Orthogonal line passes through pts(i) with direction 'normal'
                % Parametric form: [x, y] = pts(i) + t * normal
                slopes_intercepts = zeros(n_orth, 5);
                for j = 1:n_orth
                    %tidx = tidx+1;
                    x0 = pts(j, 1);
                    y0 = pts(j, 2);

                    nx = normal(1);
                    ny = normal(2);

                    if abs(nx) < eps  % vertical line
                        % For vertical line, store as inf slope
                        slopes_intercepts(i, 1) = inf;
                        slopes_intercepts(i, 2) = x0;  % x-coordinate of vertical line
                    else
                        slope = ny / nx;
                        intercept = y0 - slope * x0;
                        slopes_intercepts(j, 1) = slope; 
                        slopes_intercepts(j, 2) = intercept;
                        slopes_intercepts(j,3) = x0;
                        slopes_intercepts(j,4) = y0;
                        %slopes_intercepts(j, 5)
                    end
                end
                app.orthoAll = [app.orthoAll; slopes_intercepts];
                disp(slopes_intercepts);
            end   
        end
        
        function keyPressCallback(app,eventdata)
            if (app.isSelecting == false)
                return
            end
            if strcmp(eventdata.Key, 'g')
                % Get current mouse position in axes
                current_pt = get(app.UIAxes2, 'CurrentPoint');
                cursor_x = current_pt(1, 1);
                cursor_y = current_pt(1, 2);

                % Find the closest line to the cursor
                min_distance = inf;
                closest_line_idx = -1;
                %lineHandles = app.orthoLineHandles;
                %[n_orth,~] = size(app.orthoAll);

                for i = 1:length(app.orthoLineHandles)
                   m = app.orthoAll(i, 1);
                   b = app.orthoAll(i, 2);
                   distance = abs(m*cursor_x - cursor_y + b)/sqrt(m^2+1);
                   if (distance<min_distance)
                       min_distance = distance;
                       closest_line_idx = i;
                   end
                    % % Get line data
                    % line_x = get(lineHandles{i}, 'XData');
                    % line_y = get(lineHandles{i}, 'YData');
                    % 
                    % % Calculate distance from cursor to each point on the line
                    % distances = sqrt((line_x - cursor_x).^2 + (line_y - cursor_y).^2);
                    % min_dist_to_line = min(distances);
                    % 
                    % % Track closest line
                    % if min_dist_to_line < min_distance
                    %     min_distance = min_dist_to_line;
                    %     closest_line_idx = i;
                    % end
                end
                
                %delete the previously selected line
                if (~isempty(app.selectedOrtho))
                    handle = app.orthoLineHandles(app.selectedIdx);
                    set(handle, 'Color', 'yellow');
                    app.selectedIdx = [];
                    app.selectedOrtho = {};
                end

                handle =app.orthoLineHandles(closest_line_idx);
                set(handle, 'Color', 'cyan');
                app.selectedIdx = closest_line_idx;
                app.selectedOrtho = app.orthoAll(closest_line_idx, :);
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.clickedPts = [];
            app.drawnLineHandles = gobjects(0);
            app.orthoLineHandles = gobjects(0);
            app.drawnLineHandles2 = gobjects(0);
            app.markerHandles2 = gobjects(0);
        end

        % Button pushed function: LoadrawButton
        function LoadrawButtonPushed(app, event)
            [f, p] = uigetfile('*.tif');
            if isequal(f,0); return; end

            app.img1Path = fullfile(p,f);
            imbuffer = imread(app.img1Path);
            img1_uint8 = imbuffer;
            %img1_uint8 =im2uint8(mat2gray(imbuffer));
            if ndims(img1_uint8) == 2
                img1_uint8 = repmat(img1_uint8, 1, 1, 3);
            end
            %app.Image1.ImageSource = img1_uint8;
            imshow(img1_uint8, 'Parent', app.UIAxes1);
            app.ImagepathTextArea.Value = app.img1Path;
            hImg = findobj(app.UIAxes1, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');

            %display in axis as well?
            cla(app.UIAxes1);                       % clear previous contents
            image(app.UIAxes1, img1_uint8);         % show RGB uint8 image
            axis(app.UIAxes1, 'image');             % preserve aspect ratio / pixel aspect
            app.UIAxes1.XTick = [];                 % hide tick marks
            app.UIAxes1.YTick = [];
            app.UIAxes1.YDir = 'reverse';           % make (x,y) clicks intuitive (row increases downward)
            hold(app.UIAxes1, 'on');                % allow overlaying lines/markers

            try
                app.UIAxes1.Color = 'none';
                uistack(app.UIAxes1, 'top');
            catch
                % older versions / layout may not support uistack/layout change — ignore if fails
            end
        end

        % Button pushed function: LoadskeletonButton
        function LoadskeletonButtonPushed(app, event)
            [f, p] = uigetfile('*.tif');
            if isequal(f,0); return; end

            im2path = fullfile(p,f);
            imbuffer = imread(im2path);
            im2_uint8 = imbuffer;
            %img2_uint8 =im2uint8(mat2gray(imbuffer));
            if ndims(im2_uint8) == 2
                im2_uint8 = repmat(im2_uint8, 1, 1, 3);
            end
            imshow(im2_uint8, 'Parent', app.UIAxes2);
            hImg = findobj(app.UIAxes2, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');
            cla(app.UIAxes2);                       % clear previous contents
            image(app.UIAxes2, im2_uint8);         % show RGB uint8 image
            axis(app.UIAxes2, 'image');             % preserve aspect ratio / pixel aspect
            app.UIAxes2.XTick = [];                 % hide tick marks
            app.UIAxes2.YTick = [];
            app.UIAxes2.YDir = 'reverse';           % make (x,y) clicks intuitive (row increases downward)
            %hold(app.UIAxes2, 'on');          
        end

        % Button pushed function: DrawoutlineButton
        function DrawoutlineButtonPushed(app, event)
            app.isDrawing = true;
            app.clickedPts = [];
            % Clear existing graphics safely
            if ~isempty(app.drawnLineHandles)
                delete(app.drawnLineHandles);
            end
            if ~isempty(app.markerHandles)
                delete(app.markerHandles);
            end

             app.markerHandles = gobjects(0);
             app.drawnLineHandles = gobjects(0);
             app.UIFigure.WindowButtonDownFcn = @(src,evt) onMouseClick(app, src, evt);

             % Make sure axis is on top and ready for overlays
             try
                 app.UIAxes1.Color = 'none';
                 uistack(app.UIAxes1, 'top');
             catch
             end
             hold(app.UIAxes1, 'on');
        end

        % Button pushed function: SaveoutlineButton
        function SaveoutlineButtonPushed(app, event)
            hold(app.UIAxes1, 'off');
            app.isDrawing = false;
            app.UIFigure.WindowButtonDownFcn = [];
            calculate_orthognals(app);
            disp(app.orthoAll);
        end

        % Value changed function: TotalorthogonalEditField
        function TotalorthogonalEditFieldValueChanged(app, event)
            comp = app.TotalorthogonalEditField.Value;
            val = comp;
            if isnumeric(val) && isscalar(val) && ~isnan(val)
                app.totalOrthL = val;
            else
                if ischar(val) || isstring(val)
                    val = str2double(val);
                    if ~isnan(val)
                        app.totalOrthL = val;
                    end
                end
            end
        end

        % Button pushed function: DeleteoutlineButton
        function DeleteoutlineButtonPushed(app, event)
            app.clickedPts = [];
            % Clear existing graphics
            if ~isempty(app.drawnLineHandles)
                delete(app.drawnLineHandles);
            end
            if ~isempty(app.markerHandles)
                delete(app.markerHandles);
            end
            if ~isempty(app.orthoLineHandles)
                delete(app.orthoLineHandles);
            end
        end

        % Button pushed function: DisplayonskeletonButton
        function DisplayonskeletonButtonPushed(app, event)
            % Get axes limits to determine line endpoints
            axes(app.UIAxes2);
            xlim = get(app.UIAxes2, 'XLim');
            ylim = get(app.UIAxes2, 'YLim');
            xmin = xlim(1);
            xmax = xlim(2);
            if (~isempty(app.orthoLineHandles))
                delete(app.orthoLineHandles);
                delete(app.markerHandles2);
                delete(app.drawnLineHandles2);
                app.orthoLineHandles = gobjects(0);
                app.markerHandles2 = gobjects(0);
                app.drawnLineHandles2 = gobjects(0);
            end        
            hold(app.UIAxes2, 'on');
            [dotsn, ~] = size(app.clickedPts);
            seg_n = dotsn-1;
            %plotting the clicked dots AND the line between them
            for i = 1:dotsn
               d = plot(app.UIAxes2, app.clickedPts(i,1), app.clickedPts(i,2), 'ro', 'MarkerSize',6);
               app.markerHandles2(end+1) = d;
            end
            for i = 1:seg_n
                p1 = app.clickedPts(i, :);
                p2 = app.clickedPts(i+1, :);
                h = line(app.UIAxes2, [p1(1) p2(1)], [p1(2), p2(2)],  'Color','r','LineWidth',1.5);
                app.drawnLineHandles2(end+1) = h;
            end

            %calculate_orthognals(app);
            %getting the slope and intercpts from the app variable
            [ron, ~] =size(app.orthoAll);
            for j = 1:ron
                slope = app.orthoAll(j, 1);
                intercept = app.orthoAll(j, 2);
                y_at_xmin = slope*xmin + intercept;
                y_at_xmax = slope*xmax + intercept;
                l = plot(app.UIAxes2, [xmin xmax], [y_at_xmin y_at_xmax], 'Color','yellow', 'LineWidth',0.5);
                app.orthoLineHandles(end+1) = l;
            end
            % for i = 1:numel(app.orthoAll)
            % 
            %     intercepts = app.orthoAll{i}(:, 2);
            %     slopes = app.orthoAll{i}(:, 1);
            %     for j = 1:numel(intercepts)
            %         y_at_xmin = slopes(j) * xmin + intercepts(j);
            %         y_at_xmax = slopes(j) * xmax + intercepts(j);
            %         l = plot(app.UIAxes2, [xmin xmax], [y_at_xmin y_at_xmax], 'Color', 'yellow', 'LineWidth',0.5);
            %         app.orthoLineHandles{end+1} = l;
            %     end
            % end
          hold (app.UIAxes2, 'off');  
          app.UIAxes2.XLim = [xmin xmax];
          app.UIAxes2.YLim = [ylim(1) ylim(2)];

        end

        % Button pushed function: SelectgButton
        function SelectgButtonPushed(app, event)
            app.isSelecting = true;
            app.UIFigure.KeyPressFcn = @(src, eventdata) keyPressCallback(app, eventdata);
        end

        % Button pushed function: StopselectingButton
        function StopselectingButtonPushed(app, event)
            app.isSelecting = false;
            %TODO make the selected line go back to original color
            if (~isempty(app.selectedIdx))
                handle = app.orthoLineHandles{app.selectedIdx};
                set(handle, 'Color', 'yellow');
            end
        end

        % Button pushed function: ConfirmoutputButton
        function ConfirmoutputButtonPushed(app, event)
            [folder, ~, ~] = fileparts(app.img1Path);
            filepath = fullfile(folder, 'Orthogonal.mat');
            selected_orth = app.orthoAll(app.selectedIdx, :);
            result.slope = selected_orth(1);
            result.intercept = selected_orth(2);
            result.startPoint = [selected_orth(3) selected_orth(4)];
            result.border = app.clickedPts;
            save(filepath, "result");
            app.OutputstatusTextArea.Value = filepath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Axon mask')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Image')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.LoadrawButton = uibutton(app.UIFigure, 'push');
            app.LoadrawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawButtonPushed, true);
            app.LoadrawButton.Text = 'Load raw';
            app.LoadskeletonButton = uibutton(app.UIFigure, 'push');
            app.LoadskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @LoadskeletonButtonPushed, true);
            app.LoadskeletonButton.Text = 'Load skeleton';
            app.ImagepathTextAreaLabel = uilabel(app.UIFigure);
            app.ImagepathTextAreaLabel.Text = 'Image path';
            app.ImagepathTextArea = uitextarea(app.UIFigure);
            app.DeleteoutlineButton = uibutton(app.UIFigure, 'push');
            app.DeleteoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteoutlineButtonPushed, true);
            app.DeleteoutlineButton.Text = 'Delete outline';
            app.DrawoutlineButton = uibutton(app.UIFigure, 'push');
            app.DrawoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawoutlineButtonPushed, true);
            app.DrawoutlineButton.Text = 'Draw outline';
            app.SaveoutlineButton = uibutton(app.UIFigure, 'push');
            app.SaveoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutlineButtonPushed, true);
            app.SaveoutlineButton.Text = 'Save outline';
            app.DisplayonskeletonButton = uibutton(app.UIFigure, 'push');
            app.DisplayonskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayonskeletonButtonPushed, true);
            app.DisplayonskeletonButton.Text = 'Display on skeleton';
            app.ConfirmoutputButton = uibutton(app.UIFigure, 'push');
            app.ConfirmoutputButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmoutputButtonPushed, true);
            app.ConfirmoutputButton.Text = 'Confirm & output';
            app.OutputstatusTextAreaLabel = uilabel(app.UIFigure);
            app.OutputstatusTextAreaLabel.Text = 'Output status';
            app.OutputstatusTextArea = uitextarea(app.UIFigure);
            app.StopselectingButton = uibutton(app.UIFigure, 'push');
            app.StopselectingButton.ButtonPushedFcn = createCallbackFcn(app, @StopselectingButtonPushed, true);
            app.StopselectingButton.Text = 'Stop selecting';
            app.TotalorthogonalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalorthogonalEditFieldLabel.Text = 'Total orthogonal';
            app.TotalorthogonalEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalorthogonalEditField.ValueChangedFcn = createCallbackFcn(app, @TotalorthogonalEditFieldValueChanged, true);
            app.SelectgButton = uibutton(app.UIFigure, 'push');
            app.SelectgButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgButtonPushed, true);
            app.SelectgButton.Text = 'Select(g)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxonSkewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end